classdef vitalSignsDemo_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TexasInstrumentsVitalSignsMonitoringDemoUIFigure  matlab.ui.Figure
        PhaseUnwrapped             matlab.ui.control.UIAxes
        HeartRateWfm               matlab.ui.control.UIAxes
        LoadConfigFile             matlab.ui.control.CheckBox
        RangeProfile               matlab.ui.control.UIAxes
        SaveData                   matlab.ui.control.CheckBox
        BRpkLabel                  matlab.ui.control.Label
        Temp1Display               matlab.ui.control.NumericEditField
        HRpkLabel                  matlab.ui.control.Label
        Temp2Display               matlab.ui.control.NumericEditField
        THBreathLabel              matlab.ui.control.Label
        ThresholdBreathing         matlab.ui.control.Spinner
        THHeartLabel               matlab.ui.control.Label
        ThresholdHeart             matlab.ui.control.Spinner
        BRFTLabel                  matlab.ui.control.Label
        Temp1Display_2             matlab.ui.control.NumericEditField
        HRFTLabel                  matlab.ui.control.Label
        Temp2Display_2             matlab.ui.control.NumericEditField
        MotionEditFieldLabel       matlab.ui.control.Label
        MotionFlagEditField        matlab.ui.control.NumericEditField
        BreathingWfm               matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        Start                      matlab.ui.control.Button
        SettingsButton             matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        Plots_Enable               matlab.ui.control.CheckBox
        Plot_Displacement          matlab.ui.control.CheckBox
        Plot_RangeProfile          matlab.ui.control.CheckBox
        HeartRateVariabilityPanel  matlab.ui.container.Panel
        RMSSDLabel                 matlab.ui.control.Label
        RMSSD_val                  matlab.ui.control.NumericEditField
        SDNNLabel_2                matlab.ui.control.Label
        SDNN_val                   matlab.ui.control.NumericEditField
        HTILabel_2                 matlab.ui.control.Label
        HTI_val                    matlab.ui.control.NumericEditField
        TimerShow                  matlab.ui.control.EditField
    end


    properties (Access = public)
                EXIT_PRESSED;         
                PAUSED_PRESSED;
                REFRESH_PRESSED;
                FFT_SPECTRAL_EST_ENABLE;
                CLI_SEND_FLAG;
                comport_UserUARTnum;       
                comport_DataPortnum;
                settings = {'profile_2d_VitalSigns_20fps.cfg','4','3'};      
    end

    properties (Access = private)
        Timer % Timer for the GUI clock
    end

    methods (Access = private)
    
        function TimerRefresh(app, obj, data_)
            info = get(app.Timer);
            seconds = string(mod(double(info.TasksExecuted),60)-1);
            minutes = info.TasksExecuted / 60;
            minutes = cast(minutes, 'int32');
            time = strcat(string(minutes),':',pad(seconds,2,'left','0'));
            app.TimerShow.Value = char(time);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        %app.Button.Icon = 'tiLogo2.png';  % Texas Instruments Logo  
                        app.EXIT_PRESSED = 0;             % Flag for the Stop PushButton 
                        app.REFRESH_PRESSED = 0;          % Flag for the Refresh PushButton
                        app.PAUSED_PRESSED = 0;           % Flag for the Pause PushButton 
                        app.Timer = timer('ExecutionMode', 'fixedRate'); % Create the timer object
                        app.Timer.TimerFcn = @app.TimerRefresh; % Tell the timer to run TimerRefresh every second.
        end

        % Callback function
        function RefreshButtonPressedButtonPushed(app, event)
            app.REFRESH_PRESSED = 1;
            app.Start.BackgroundColor = [0.94 0.94 0.94];
            stop(app.Timer);
        end

        % Callback function
        function SettingsButtonPushed(app, event)
        prompt = {'Configuration File name:','User UART Port', 'Auxilliary Data Port'};
        dlg_title = 'Settings';
        num_lines = 1;
       
        defaultans = {'profile_2d_VitalSigns_20fps.cfg','4','3'};
        app.settings = inputdlg(prompt,dlg_title,num_lines,defaultans);        
        stop(app.Timer);
        end

        % Callback function
        function StartPushed(app, event)
            loadConfig = 0;
            app.EXIT_PRESSED = 0;
            app.PAUSED_PRESSED = 0;
            
            app.Start.BackgroundColor = 'red';
            
            if(app.LoadConfigFile.Value)
                loadConfig = 1;
            end
            configFileName     = app.settings{1};
            comPortnumber_CLI  = str2double(app.settings{2});
            comPortnumber_DATA = str2double(app.settings{3});
            
            % Start the timer.
            start(app.Timer);
            pause(300);
            % run the vital signs function (computes HRV values.)
            %vitalSigns_demo_gui(comPortnumber_DATA,comPortnumber_CLI,configFileName,loadConfig, app);           
            % Load the HRV values.
            %app.SDNN_val.value = SDNN;
            %app.RMSSD_val.value = RMSSD;
            %app.HTI_val.value = HTI;
            stop(app.Timer);
            app.Start.BackgroundColor = [0.94 0.94 0.94];
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.EXIT_PRESSED = 1;
            app.LoadConfigFile.Value = 1; 
            app.Start.BackgroundColor = [0.94 0.94 0.94];
            stop(app.Timer)
        end

        % Callback function
        function sizeChangedFcn(app, event)
      
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure = uifigure;
            app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure.Name = 'Texas Instruments Vital Signs Monitoring Demo';
            app.PhaseUnwrapped = uiaxes(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            title(app.PhaseUnwrapped, 'Chest Displacement')
            xlabel(app.PhaseUnwrapped, 'Frame Index')
            ylabel(app.PhaseUnwrapped, 'Displacement (mm)')
            app.HeartRateWfm = uiaxes(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            title(app.HeartRateWfm, 'Heart Waveform')
            app.LoadConfigFile = uicheckbox(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.LoadConfigFile.Text = 'Load Config File';
            app.LoadConfigFile.Value = true;
            app.RangeProfile = uiaxes(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            title(app.RangeProfile, 'Range Profile')
            xlabel(app.RangeProfile, 'Range (meter)')
            app.SaveData = uicheckbox(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.SaveData.Text = 'Save Data';
            app.BRpkLabel = uilabel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.BRpkLabel.Text = 'BR-pk';
            app.Temp1Display = uieditfield(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure, 'numeric');
            app.Temp1Display.Editable = 'off';
            app.HRpkLabel = uilabel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.HRpkLabel.Text = 'HR-pk';
            app.Temp2Display = uieditfield(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure, 'numeric');
            app.Temp2Display.Editable = 'off';
            app.THBreathLabel = uilabel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.THBreathLabel.Text = 'TH Breath';
            app.ThresholdBreathing = uispinner(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.ThresholdBreathing.Value = 10;
            app.THHeartLabel = uilabel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.THHeartLabel.Text = 'TH  Heart';
            app.ThresholdHeart = uispinner(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.ThresholdHeart.Step = 0.1;
            app.ThresholdHeart.Value = 0.4;
            app.BRFTLabel = uilabel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.BRFTLabel.Text = 'BR-FT';
            app.Temp1Display_2 = uieditfield(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure, 'numeric');
            app.Temp1Display_2.Editable = 'off';
            app.HRFTLabel = uilabel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.HRFTLabel.Text = 'HR-FT';
            app.Temp2Display_2 = uieditfield(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure, 'numeric');
            app.Temp2Display_2.Editable = 'off';
            app.MotionEditFieldLabel = uilabel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.MotionEditFieldLabel.Text = 'Motion';
            app.MotionFlagEditField = uieditfield(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure, 'numeric');
            app.MotionFlagEditField.Editable = 'off';
            app.BreathingWfm = uiaxes(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            title(app.BreathingWfm, 'Breathing Waveform')
            app.Panel = uipanel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.Start = uibutton(app.Panel, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartPushed, true);
            app.Start.Text = 'Start';
            app.SettingsButton = uibutton(app.Panel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPressedButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Plots_Enable = uicheckbox(app.Panel);
            app.Plots_Enable.Text = 'Enable Plots';
            app.Plots_Enable.Value = true;
            app.Plot_Displacement = uicheckbox(app.Panel);
            app.Plot_Displacement.Text = 'Plot Displacement';
            app.Plot_RangeProfile = uicheckbox(app.Panel);
            app.Plot_RangeProfile.Text = 'Plot Range Profile';
            app.Plot_RangeProfile.Value = true;
            app.HeartRateVariabilityPanel = uipanel(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure);
            app.HeartRateVariabilityPanel.Title = 'Heart Rate Variability';
            app.RMSSDLabel = uilabel(app.HeartRateVariabilityPanel);
            app.RMSSDLabel.Text = 'RMSSD';
            app.RMSSD_val = uieditfield(app.HeartRateVariabilityPanel, 'numeric');
            app.RMSSD_val.ValueDisplayFormat = '%3.0f';
            app.RMSSD_val.Editable = 'off';
            app.SDNNLabel_2 = uilabel(app.HeartRateVariabilityPanel);
            app.SDNNLabel_2.Text = 'SDNN';
            app.SDNN_val = uieditfield(app.HeartRateVariabilityPanel, 'numeric');
            app.SDNN_val.ValueDisplayFormat = '%3.0f';
            app.SDNN_val.Editable = 'off';
            app.HTILabel_2 = uilabel(app.HeartRateVariabilityPanel);
            app.HTILabel_2.Text = 'HTI';
            app.HTI_val = uieditfield(app.HeartRateVariabilityPanel, 'numeric');
            app.HTI_val.ValueDisplayFormat = '%3.0f';
            app.HTI_val.Editable = 'off';
            app.TimerShow = uieditfield(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure, 'text');
            app.TimerShow.Editable = 'off';
            app.TimerShow.Value = '0:00';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vitalSignsDemo_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TexasInstrumentsVitalSignsMonitoringDemoUIFigure)
        end
    end
end